classdef robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Knob                   matlab.ui.control.Knob
        KnobLabel              matlab.ui.control.Label
        TorqueSwitch           matlab.ui.control.Switch
        TorqueSwitchLabel      matlab.ui.control.Label
        StatusLamp             matlab.ui.control.Lamp
        DisconnectedLampLabel  matlab.ui.control.Label
        PortDropDown           matlab.ui.control.DropDown
        PortDropDownLabel      matlab.ui.control.Label
        ConnectButton          matlab.ui.control.Button
    end


    properties (Access = private)
        isConnected = false % Track connection state
        launcherInstance;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            screenSize = get(0, 'ScreenSize'); % Get the screen size
            appWidth = screenSize(3) / 3; % App width is 1/3 of the screen width
            appHeight = screenSize(4); % App height is the full screen height

            % Set the position of the app
            app.UIFigure.Position = [app.screenSize(3) - appWidth, 1, appWidth, appHeight];


            % Set UIFigure to visible
            app.UIFigure.Visible = 'on';

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            selectedPort = app.PortDropDown.Value;
            app.launcherInstance = Launcher.getInstance();

            if ~app.isConnected
                % Connect logic...
                try
                    app.launcherInstance.connect(selectedPort);
                    app.DisconnectedLampLabel.Text = 'Connected';
                    app.StatusLamp.Color = [0, 1, 0]; % Green color
                    app.ConnectButton.Text = 'Disconnect';
                    app.isConnected = true;

                    % Make TorqueSwitch visible
                    app.TorqueSwitch.Visible = 'on';

                    % Execute UpdateAndPlotProgram
                    app.launcherInstance.setProgram('UpdateAndPlotProgram');


                catch ME
                    app.DisconnectedLampLabel.Text = ['Error: ', ME.message];
                    app.StatusLamp.Color = [1, 0, 0]; % Red color
                    % Ensure TorqueSwitch is hidden
                    app.TorqueSwitch.Visible = 'off';
                end
            else
                % Disconnect logic...
                app.launcherInstance.disconnect();
                app.DisconnectedLampLabel.Text = 'Disconnected';
                app.StatusLamp.Color = [1, 0, 0]; % Red color
                app.ConnectButton.Text = 'Connect';
                app.isConnected = false;

                % Hide TorqueSwitch
                app.TorqueSwitch.Visible = 'off';

                % Close all open figures
                close all;
            end
        end

        % Value changed function: TorqueSwitch
        function TorqueSwitchValueChanged(app, event)
            value = app.TorqueSwitch.Value;

            % Check the value of the switch
            if strcmp(value, 'On')
                % If the switch is 'On', enable the robot's torque
                app.launcherInstance.realRobot.torqueEnable;
                disp('Torque Enabled');
            elseif strcmp(value, 'Off')
                % If the switch is 'Off', disable the robot's torque
                app.launcherInstance.realRobot.torqueDisable;
                disp('Torque Disabled');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port:';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8'};
            app.PortDropDown.Value = 'COM3';
            app.DisconnectedLampLabel = uilabel(app.UIFigure);
            app.DisconnectedLampLabel.Tag = 'Text';
            app.DisconnectedLampLabel.Text = 'Disconnected';
            app.StatusLamp = uilamp(app.UIFigure);
            app.TorqueSwitchLabel = uilabel(app.UIFigure);
            app.TorqueSwitchLabel.Visible = 'off';
            app.TorqueSwitchLabel.Text = 'Torque';
            app.TorqueSwitch = uiswitch(app.UIFigure, 'slider');
            app.TorqueSwitch.ValueChangedFcn = createCallbackFcn(app, @TorqueSwitchValueChanged, true);
            app.TorqueSwitch.Visible = 'off';
            app.TorqueSwitch.Value = 'On';
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end